classdef CalculosApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        VolverButton              matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        GraficarCategoriasButton  matlab.ui.control.Button
        TopCarosButton            matlab.ui.control.Button
        ValorInventarioButton     matlab.ui.control.Button
        StockBajoButton           matlab.ui.control.Button
        ResultadoLabel            matlab.ui.control.Label
        TotalProductoButton       matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function CalcularButtonPushed(app, event)
           conn = conectarPostgres();
    data = fetch(conn, 'SELECT * FROM personas');
    close(conn);

    % Calculos
    total = height(data);
    edadProm = mean(data.edad);
    porcentajeDiabetes = 100 * sum(data.tiene_diabetes) / total;
    porcentajeHipertension = 100 * sum(data.tiene_hipertension) / total;

    % Mostrar
    app.NumPersonasEditField.Value = total;
    app.EdadPromedioEditField.Value = round(edadProm, 2);
    app.DiabetesEditField.Value = sprintf('%.1f%%', porcentajeDiabetes);
    app.HipertensionEditField.Value = sprintf('%.1f%%', porcentajeHipertension);
        end

        % Button pushed function: TotalProductoButton
        function TotalProductoButtonPushed(app, event)
            data = fetch(app.conn, 'SELECT COUNT(*) AS total FROM productos');
            app.ResultadoLabel.Text = ['Total de productos: ', num2str(data.total)];
        end

        % Button pushed function: StockBajoButton
        function StockBajoButtonPushed(app, event)
            data = fetch(app.conn, 'SELECT COUNT(*) AS bajo FROM productos WHERE cantidad < 10');
            app.ResultadoLabel.Text = ['Productos con stock bajo: ', num2str(data.bajo)];
        end

        % Button pushed function: ValorInventarioButton
        function ValorInventarioButtonPushed(app, event)
           data = fetch(app.conn, 'SELECT SUM(precio * cantidad) AS total_valor FROM productos');
           total = data.total_valor;
     if isempty(total)
        total = 0;
    end
    app.ResultadoLabel.Text = ['Valor total del inventario: $ ', num2str(total, '%.2f')]; 
        end

        % Button pushed function: TopCarosButton
        function TopCarosButtonPushed(app, event)
            query = "SELECT nombre, precio FROM productos ORDER BY precio DESC LIMIT 5";
            data = fetch(app.conn, query);
            app.UITable.Data = data;
            app.ResultadoLabel.Text = 'Top 5 productos más caros mostrados en la tabla.';
        end

        % Button pushed function: GraficarCategoriasButton
        function GraficarCategoriasButtonPushed(app, event)
            query = "SELECT categoria, SUM(cantidad) AS stock FROM productos GROUP BY categoria";
    data = fetch(app.conn, query);

    if ~isempty(data)
        bar(app.UIAxes, categorical({data.categoria}), [data.stock]);
        title(app.UIAxes, 'Stock por Categoría');
        xlabel(app.UIAxes, 'Categoría');
        ylabel(app.UIAxes, 'Unidades');
        app.ResultadoLabel.Text = 'Gráfica generada con éxito.';
    else
        app.ResultadoLabel.Text = 'No hay datos para graficar.';
    end
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
        '¿Estás seguro de que deseas salir del sistema?', ...  % Mensaje
        'Confirmar salida', ...                                 % Título
        'Options', {'Sí', 'No'}, ...                            % Botones
        'DefaultOption', 2, ...                                 % Opción por defecto: No
        'CancelOption', 2);  
         if strcmp(selection, 'Sí')
            MainMenuApp;
         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TotalProductoButton = uibutton(app.UIFigure, 'push');
            app.TotalProductoButton.ButtonPushedFcn = createCallbackFcn(app, @TotalProductoButtonPushed, true);
            app.TotalProductoButton.Text = 'TotalProducto';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.StockBajoButton = uibutton(app.UIFigure, 'push');
            app.StockBajoButton.ButtonPushedFcn = createCallbackFcn(app, @StockBajoButtonPushed, true);
            app.StockBajoButton.Text = 'StockBajo';
            app.ValorInventarioButton = uibutton(app.UIFigure, 'push');
            app.ValorInventarioButton.ButtonPushedFcn = createCallbackFcn(app, @ValorInventarioButtonPushed, true);
            app.ValorInventarioButton.Text = 'ValorInventario';
            app.TopCarosButton = uibutton(app.UIFigure, 'push');
            app.TopCarosButton.ButtonPushedFcn = createCallbackFcn(app, @TopCarosButtonPushed, true);
            app.TopCarosButton.Text = 'TopCaros';
            app.GraficarCategoriasButton = uibutton(app.UIFigure, 'push');
            app.GraficarCategoriasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarCategoriasButtonPushed, true);
            app.GraficarCategoriasButton.Text = 'Graficar-Categorias';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalculosApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end